classdef Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Formatlower_limitupper_limitLabel  matlab.ui.control.Label
        TransferFunctionTextArea       matlab.ui.control.TextArea
        TransferFunctionTextAreaLabel  matlab.ui.control.Label
        Formata0a1a2Label_2            matlab.ui.control.Label
        Formata0a1a2Label              matlab.ui.control.Label
        AnalysisButton                 matlab.ui.control.Button
        FrequencyRangesEditField       matlab.ui.control.EditField
        FrequencyRangesEditFieldLabel  matlab.ui.control.Label
        DenominatorEditField           matlab.ui.control.EditField
        DenominatorEditFieldLabel      matlab.ui.control.Label
        NumeratorEditField             matlab.ui.control.EditField
        NumeratorEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        num = [];
        denom = [];
        freq = []; 
    end
    
    methods (Access = private)
        
        function tf_string = tf2string(app) % Convert user input to transfer funtion display
            num_str = poly2str(app.num, 's');
            den_str = poly2str(app.denom, 's');
        
            tf_string = ['(' num2str(num_str) ') / (' num2str(den_str) ')'];
        end
        
        function bode_plot(app) % self implementation bode plot
            syms s
            % Covnert user input to numerator and denominator format
            % accecpted by poly function in matlab_______
            num = poly2sym(app.num, s);
            den = poly2sym(app.denom, s);
            %____________
            f(s) = num/den; % System transfer function

            w = linspace(app.freq(1), app.freq(2), app.freq(2)-app.freq(1)+1); % Range of the frequency for bode plot
            f_t = subs(f, s, 1j*w); % Substitute j*w to s in the transfer function
            f_t_abs = abs(f_t); % Calculate the magnitude of the complex transfer function number
            f_t_abs_log = 20*log10(f_t_abs); % Convert to dB (Magnitude of Bode Plot)
            f_t_arg = rad2deg(angle(f_t)); % Calculate the angle for Bode Plote
            
            figure("Name", "Bode Plot")
            subplot(211);semilogx(w,f_t_abs_log); % Plot Magnitude Bode Plot
            subplot(212);semilogx(w,f_t_arg); % Plot Phase Bode Plote
        end

        function coeff_vector = str2coeff(app, string)
            % Split the string by spaces
            parts = strsplit(string, ' ');
            % Preallocate a numerical array
            coeff_vector = zeros(1, numel(parts));
            
            % Convert each part to numerical value
            for i = 1:numel(parts)
                % Extract the numerical part of the string (excluding 'a')
                numStr = parts(i);
                
                % Convert to numerical value
                coeff_vector(i) = str2double(numStr);
            end
        end

        function displayInfo(app, sys) % Display system info in a fiugre
            S = stepinfo(sys);

            figure("Name", "System Info")
            axis off
            
            text(0.1, 0.9, sprintf('Rise Time: %.4f\n', S.RiseTime), 'FontSize', 12)
            text(0.1, 0.8, sprintf('Settling Time: %.4f\n', S.SettlingTime), 'FontSize', 12)
            text(0.1, 0.7, sprintf('Settling Min: %.4f\n', S.SettlingMin), 'FontSize', 12)
            text(0.1, 0.6, sprintf('Settling Max: %.4f\n', S.SettlingMax), 'FontSize', 12)
            text(0.1, 0.5, sprintf('Overshoot: %.4f\n', S.Overshoot), 'FontSize', 12)
            text(0.1, 0.4, sprintf('Undershoot: %.4f\n', S.Undershoot), 'FontSize', 12)
            text(0.1, 0.3, sprintf('Peak: %.4f\n', S.Peak), 'FontSize', 12)
            text(0.1, 0.2, sprintf('Peak Time: %.4f\n', S.PeakTime), 'FontSize', 12)
            text(0.1, 0.1, sprintf('Settling Time: %.4f\n', S.SettlingTime), 'FontSize', 12)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumeratorEditField
        function NumeratorEditFieldValueChanged(app, event)
            value = app.NumeratorEditField.Value;
            app.num = app.str2coeff(value);
            % display(app.num)
        end

        % Value changed function: DenominatorEditField
        function DenominatorEditFieldValueChanged(app, event)
            value = app.DenominatorEditField.Value;
            app.denom = app.str2coeff(value);
            % display(app.denom)
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            display(app.num)
            sys = tf(app.num,app.denom);
            tf_string = app.tf2string();
            app.TransferFunctionTextArea.Value = tf_string;
            pause(2) % Pause 2 seconds to let users read the transfer function

            figure("Name", "Step")
            step(sys); % Step Response

            app.bode_plot() % Self Implementation Bode Plote

            figure("Name", "Margin")
            margin(sys) % Phase and Magnitude response

            figure("Name", "Root Locus")
            rlocus(sys) % Root Locus

            app.displayInfo(sys)
        end

        % Value changed function: FrequencyRangesEditField
        function FrequencyRangesEditFieldValueChanged(app, event)
            value = app.FrequencyRangesEditField.Value;
            app.freq = app.str2coeff(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumeratorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.UIFigure, 'text');
            app.NumeratorEditField.ValueChangedFcn = createCallbackFcn(app, @NumeratorEditFieldValueChanged, true);
            app.DenominatorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominatorEditField.ValueChangedFcn = createCallbackFcn(app, @DenominatorEditFieldValueChanged, true);
            app.FrequencyRangesEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyRangesEditFieldLabel.Text = 'Frequency Ranges';
            app.FrequencyRangesEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyRangesEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangesEditFieldValueChanged, true);
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.Formata0a1a2Label = uilabel(app.UIFigure);
            app.Formata0a1a2Label.Text = 'Format: "a0 a1 a2..."';
            app.Formata0a1a2Label_2 = uilabel(app.UIFigure);
            app.Formata0a1a2Label_2.Text = 'Format: "a0 a1 a2..."';
            app.TransferFunctionTextAreaLabel = uilabel(app.UIFigure);
            app.TransferFunctionTextAreaLabel.Text = 'Transfer Function';
            app.TransferFunctionTextArea = uitextarea(app.UIFigure);
            app.TransferFunctionTextArea.Editable = 'off';
            app.Formatlower_limitupper_limitLabel = uilabel(app.UIFigure);
            app.Formatlower_limitupper_limitLabel.Text = 'Format: "lower_limit upper_limit"';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end